classdef P01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LimI_txt                    matlab.ui.control.Label
        Aclaracion                  matlab.ui.control.Label
        LimS_txt                    matlab.ui.control.Label
        Aceptar                     matlab.ui.control.Button
        UPIITA_LOGO                 matlab.ui.control.Image
        IPN_LOGO                    matlab.ui.control.Image
        Encabezado                  matlab.ui.control.Label
        Advertencia                 matlab.ui.control.Button
        Senal                       matlab.ui.control.TextArea
        SealaGraficarTextAreaLabel  matlab.ui.control.Label
        Construir                   matlab.ui.control.Button
        VEscV                       matlab.ui.control.NumericEditField
        VTrasV                      matlab.ui.control.NumericEditField
        VEscH                       matlab.ui.control.NumericEditField
        TF                          matlab.ui.control.Label
        TH                          matlab.ui.control.Label
        VTrasH                      matlab.ui.control.NumericEditField
        LI                          matlab.ui.control.NumericEditField
        LS                          matlab.ui.control.NumericEditField
        EscV                        matlab.ui.control.CheckBox
        TrasV                       matlab.ui.control.CheckBox
        EscH                        matlab.ui.control.CheckBox
        TrasH                       matlab.ui.control.CheckBox
        Grafica                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TrasH
        function TrasHValueChanged(app, event)
            global TH Encendido Apagado
            TH=app.TrasH.Value;
            Encendido= 'on';
            Apagado='off';
            if(TH==1)
            app.VTrasH.Enable=Encendido;
            else
            app.VTrasH.Enable=Apagado;
            app.VTrasH.Value=0;
            end
        end

        % Value changed function: EscH
        function EscHValueChanged(app, event)
            global EH Encendido Apagado
            EH=app.EscH.Value;
            if(EH==1)
            app.VEscH.Enable=Encendido;
            else
            app.VEscH.Enable=Apagado;
            app.VEscH.Value=1;
            end
            
        end

        % Value changed function: TrasV
        function TrasVValueChanged(app, event)
            global TV Encendido Apagado
            TV=app.TrasV.Value;
            if(TV==1)
            app.VTrasV.Enable=Encendido;
            else
            app.VTrasV.Enable=Apagado;
            app.VTrasV.Value=0;
            end
        end

        % Value changed function: EscV
        function EscVValueChanged(app, event)
            global EV Encendido Apagado
            EV=app.EscV.Value;
            if(EV==1)
            app.VEscV.Enable=Encendido;
            else
            app.VEscV.Enable=Apagado;
            app.VEscV.Value=1;
            end
        end

        % Button pushed function: Advertencia
        function AdvertenciaButtonPushed(app, event)
            global Encendido Apagado
            Encendido= 'on';
            Apagado='off';
            app.Aclaracion.Visible=Encendido;
            app.Aceptar.Visible=Encendido;
            app.Advertencia.Enable=Apagado;
            app.Grafica.Visible=Apagado;
            app.VEscH.Visible=Apagado;
            app.VEscV.Visible=Apagado;
            app.VTrasH.Visible=Apagado;
            app.VTrasV.Visible=Apagado;
            app.TF.Visible=Apagado;
            app.TH.Visible=Apagado;
            app.EscH.Visible=Apagado;
            app.EscV.Visible=Apagado;
            app.TrasH.Visible=Apagado;
            app.TrasV.Visible=Apagado;
            app.LI.Visible=Apagado;
            app.LS.Visible=Apagado;
            app.LI.Visible=Apagado;
            app.LS.Visible=Apagado;
            app.Construir.Visible=Apagado;
            app.LimI_txt.Visible=Apagado;
            app.LimS_txt.Visible=Apagado;
            app.Senal.Enable=Apagado;
        end

        % Button pushed function: Aceptar
        function AceptarButtonPushed(app, event)
            global Encendido Apagado 
            Encendido= 'on';
            Apagado='off';
            app.Aclaracion.Visible=Apagado;
            app.Aceptar.Visible=Apagado;
            app.Advertencia.Enable=Encendido;
            app.Grafica.Visible=Encendido;
            app.VEscH.Visible=Encendido;
            app.VEscV.Visible=Encendido;
            app.VTrasH.Visible=Encendido;
            app.VTrasV.Visible=Encendido;
            app.TF.Visible=Encendido;
            app.TH.Visible=Encendido;
            app.EscH.Visible=Encendido;
            app.EscV.Visible=Encendido;
            app.TrasH.Visible=Encendido;
            app.TrasV.Visible=Encendido;
            app.LI.Visible=Encendido;
            app.LS.Visible=Encendido;
            app.Construir.Visible=Encendido;
            app.LimI_txt.Visible=Encendido;
            app.LimS_txt.Visible=Encendido;
            app.Senal.Enable=Encendido;
        end

        % Button pushed function: Construir
        function ConstruirButtonPushed(app, event)
            global LI LS Se VTH VEH VEV VTV
            Se=str2sym(app.Senal.Value);
            VTH=app.VTrasH.Value;
            VEH=app.VEscH.Value;
            VTV=app.VTrasV.Value;
            VEV=app.VEscV.Value;
            LI=app.LI.Value;
            LS=app.LS.Value;
            syms t;
            Se_TH=subs(Se,t,t+VTH);
            Se_EV=Se_TH*VEV;
            Se_EH=subs(Se_EV,t,t*VEH);
            Se_TV=Se_EH+VTV;
            S_F=Se_TV;
            fplot(app.Grafica,S_F,[LI LS]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grafica = uiaxes(app.UIFigure);
            title(app.Grafica, 'Señal graficada')
            xlabel(app.Grafica, 't')
            ylabel(app.Grafica, 'f(t)')
            zlabel(app.Grafica, 'Z')
            app.Grafica.Visible = 'off';
            app.TrasH = uicheckbox(app.UIFigure);
            app.TrasH.ValueChangedFcn = createCallbackFcn(app, @TrasHValueChanged, true);
            app.TrasH.Visible = 'off';
            app.TrasH.Text = 'Traslacion';
            app.EscH = uicheckbox(app.UIFigure);
            app.EscH.ValueChangedFcn = createCallbackFcn(app, @EscHValueChanged, true);
            app.EscH.Visible = 'off';
            app.EscH.Text = 'Escalamiento';
            app.TrasV = uicheckbox(app.UIFigure);
            app.TrasV.ValueChangedFcn = createCallbackFcn(app, @TrasVValueChanged, true);
            app.TrasV.Visible = 'off';
            app.TrasV.Text = 'Traslacion';
            app.EscV = uicheckbox(app.UIFigure);
            app.EscV.ValueChangedFcn = createCallbackFcn(app, @EscVValueChanged, true);
            app.EscV.Visible = 'off';
            app.EscV.Text = 'Escalamiento';
            app.LS = uieditfield(app.UIFigure, 'numeric');
            app.LS.Visible = 'off';
            app.LS.Value = 5;
            app.LI = uieditfield(app.UIFigure, 'numeric');
            app.LI.Visible = 'off';
            app.LI.Value = -5;
            app.VTrasH = uieditfield(app.UIFigure, 'numeric');
            app.VTrasH.Enable = 'off';
            app.VTrasH.Visible = 'off';
            app.TH = uilabel(app.UIFigure);
            app.TH.Visible = 'off';
            app.TH.Text = {'Transformaciones'; ' Horizontales'};
            app.TF = uilabel(app.UIFigure);
            app.TF.Visible = 'off';
            app.TF.Text = {'Transformaciones'; 'Verticales'};
            app.VEscH = uieditfield(app.UIFigure, 'numeric');
            app.VEscH.Enable = 'off';
            app.VEscH.Visible = 'off';
            app.VEscH.Value = 1;
            app.VTrasV = uieditfield(app.UIFigure, 'numeric');
            app.VTrasV.Enable = 'off';
            app.VTrasV.Visible = 'off';
            app.VEscV = uieditfield(app.UIFigure, 'numeric');
            app.VEscV.Enable = 'off';
            app.VEscV.Visible = 'off';
            app.VEscV.Value = 1;
            app.Construir = uibutton(app.UIFigure, 'push');
            app.Construir.ButtonPushedFcn = createCallbackFcn(app, @ConstruirButtonPushed, true);
            app.Construir.Visible = 'off';
            app.Construir.Text = 'Construir';
            app.SealaGraficarTextAreaLabel = uilabel(app.UIFigure);
            app.SealaGraficarTextAreaLabel.Enable = 'off';
            app.SealaGraficarTextAreaLabel.Text = 'Señal a Graficar';
            app.Senal = uitextarea(app.UIFigure);
            app.Senal.Enable = 'off';
            app.Advertencia = uibutton(app.UIFigure, 'push');
            app.Advertencia.ButtonPushedFcn = createCallbackFcn(app, @AdvertenciaButtonPushed, true);
            app.Advertencia.Icon = 'Aclaracion.png';
            app.Advertencia.Enable = 'off';
            app.Advertencia.Text = '';
            app.Encabezado = uilabel(app.UIFigure);
            app.Encabezado.Text = {'Instituto Politecnico Nacional'; 'Unidad Profesional Interdisciplinaria en Ingeniería y Tecnologías Avanzadas'; 'Analisis de Señales y Sistemas 2MV1'};
            app.IPN_LOGO = uiimage(app.UIFigure);
            app.IPN_LOGO.ImageSource = 'IPN_LOGO.png';
            app.UPIITA_LOGO = uiimage(app.UIFigure);
            app.UPIITA_LOGO.ImageSource = 'upiita-logo.png';
            app.Aceptar = uibutton(app.UIFigure, 'push');
            app.Aceptar.ButtonPushedFcn = createCallbackFcn(app, @AceptarButtonPushed, true);
            app.Aceptar.Text = 'Aceptar';
            app.LimS_txt = uilabel(app.UIFigure);
            app.LimS_txt.Visible = 'off';
            app.LimS_txt.Text = 'Limite Superior';
            app.Aclaracion = uilabel(app.UIFigure);
            app.Aclaracion.Text = {'Esta app permite graficar cualquier señal de una variable, pero es necesario que se escriba'; 'como normalmente se harìa en MATLAB ademas de que esta debe estar en términos de "t" '; 'por ejemplo:'; ''; 'exp(t)+(sin(t)*headviside(t-1));'; ''; '(t^2)+2;'; ''; 'Si quiere hacerle alguna operacion a la señal escrita solamente active el botón de la operación'; ' correspondiente ademas de que las Operaciones se realizaran en en siguiente Orden:'; ''; '1.- Traslacion Horizontal'; '2.- Escalamiento Vertical '; '3.- Escalamiento Horizontal'; '4.- Traslacion Vertical'; ''};
            app.LimI_txt = uilabel(app.UIFigure);
            app.LimI_txt.Visible = 'off';
            app.LimI_txt.Text = 'Limite Inferior';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P01

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end